classdef SpindleAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure2                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_preproc                     matlab.ui.container.Tab
        Label_11                        matlab.ui.control.Label
        EditField_featureFolderPath     matlab.ui.control.EditField
        Button_featureFolder            matlab.ui.control.Button
        Label_10                        matlab.ui.control.Label
        EditField_sleepFilePath         matlab.ui.control.EditField
        Button_sleepFile                matlab.ui.control.Button
        EDFLabel                        matlab.ui.control.Label
        EditField_EDFFilePath           matlab.ui.control.EditField
        Button_EDFFile                  matlab.ui.control.Button
        Button_computePreProc_2         matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        EEGTab                          matlab.ui.container.Tab
        FramenumberDropDown             matlab.ui.control.DropDown
        FramenumberDropDownLabel        matlab.ui.control.Label
        UIAxes_EEG_QC                   matlab.ui.control.UIAxes
        Tab_Data                        matlab.ui.container.Tab
        UITable_Sleepdata               matlab.ui.control.Table
        Button_parameterSetting         matlab.ui.control.Button
        Button_computePreProc           matlab.ui.control.Button
        spindle_Tab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        DetectionResultTab              matlab.ui.container.Tab
        SpindleTot_EditField            matlab.ui.control.NumericEditField
        TotalNumberofSleepSpindlesLabel  matlab.ui.control.Label
        NumOfSpindleInFramer_EditField  matlab.ui.control.NumericEditField
        EditField_3Label_2              matlab.ui.control.Label
        FrameNum_EditField              matlab.ui.control.NumericEditField
        EpochnumberLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        Image2R                         matlab.ui.control.Image
        ImageL                          matlab.ui.control.Image
        UIAxes_spindleDetect            matlab.ui.control.UIAxes
        ResultTable                     matlab.ui.container.Tab
        saveToExcel_Button              matlab.ui.control.Button
        spindleInfo_UITable             matlab.ui.control.Table
        Label_9                         matlab.ui.control.Label
        Button_detectSpindle            matlab.ui.control.Button
        Button_EDFFolder_3              matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        EditField_FeatureFilePath       matlab.ui.control.EditField
        Tab_setting                     matlab.ui.container.Tab
        Button_saveSettingToFile_3      matlab.ui.control.Button
        Button_saveSettingToFile_2      matlab.ui.control.Button
        Button_saveSettingToFile        matlab.ui.control.Button
        Button_readSettingParFromSetting  matlab.ui.control.Button
        Panel_paramterSetting           matlab.ui.container.Panel
        Panel_procParam                 matlab.ui.container.Panel
        EditField_sample_per_30s        matlab.ui.control.NumericEditField
        sample_per_30sEditFieldLabel    matlab.ui.control.Label
        EditField_rate                  matlab.ui.control.NumericEditField
        rateEditFieldLabel              matlab.ui.control.Label
        EditField_slide_len             matlab.ui.control.NumericEditField
        slide_lengthLabel               matlab.ui.control.Label
        EditField_win_len               matlab.ui.control.NumericEditField
        win_lenLabel                    matlab.ui.control.Label
        Panel_spindle                   matlab.ui.container.Panel
        EditField_underSamplingFact     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        EditField_nNonSpindle           matlab.ui.control.NumericEditField
        NumberofnonSleepSpindlesLabel   matlab.ui.control.Label
        EditField_nSpindle              matlab.ui.control.NumericEditField
        NumberofSleepSpindlesLabel      matlab.ui.control.Label
        EDFdataPanel                    matlab.ui.container.Panel
        EditField_FS                    matlab.ui.control.NumericEditField
        SamplingrateFsLabel             matlab.ui.control.Label
        EditField_TimeRange             matlab.ui.control.NumericEditField
        TimerangesLabel                 matlab.ui.control.Label
        saveFolderPanel                 matlab.ui.container.Panel
        FiguresLabel                    matlab.ui.control.Label
        EditField_FigFolder             matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        EditField_modelSaveFolder       matlab.ui.control.EditField
        Button_FigFolder                matlab.ui.control.Button
        Button_modelFolder              matlab.ui.control.Button
    end


    properties (Access = private)
        fileEDF;       % filename for EDF data
        fileSleep;     % filename for sleep data
        folderFeature; % folder to save folder
        fileFeature;
        fileModel;
        folderFig;


        defaultModel = fullfile("Model","models.mat");
        folderPreProc = fullfile("Preproc");

        params;        % paramter to read EDF file (set in Tab_setting)

        EEG_epoch;     % SST EEG epoch
        %         feature;       % Structure to save feature coef, features, subdata, f

        storeSpindleInfo; % store sindle data
        ToTFrameNum; % Total number of frame

    end

    methods (Access = private)

        function filename= pickFile(~,filter,title)
            [file,path] = uigetfile(filter,title);
            if isequal(file,0)
                disp('User selected Cancel')
                %                filename = "";
            else
                disp(['User selected ', fullfile(path, file)])
                filename = fullfile(path, file);
            end

        end


        function plot_EEGqc(~,ax, data)
            cla(ax,'reset')
            x = 1:size(data,1);
            hold(ax,'on')
            plot(ax,x,data)
            xlabel(ax,'time [s]')
            ylabel(ax,'[μV]')
            grid(ax,'on')

        end

        function progressDetectSpindle(app)

            d = uiprogressdlg(app.UIFigure2,'Title','Spindleを検出中',...
                'Indeterminate','on');
            drawnow

            load (app.fileFeature )
            load (app.fileModel )

            [~,file,~] =  fileparts(app.fileFeature);
            dirname = fullfile(app.folderFig,file);

            [TestData_Y,TestData_st_Y] = createDataset_Fromfeature(Features,SubData,false);

            RUSB_estimation = predict(Models.RUSB_model,TestData_Y);

            %スピンドルの検出結果の可視化
            params = app.params;
            [~,Spindle_num,spindleToT]= Calc_ConsufionMat(TestData_st_Y,RUSB_estimation,params,params.sample_per_30s,dirname);

            Spindle_num.SpindleStartE1 = cellfun(@(x)SpindleTimeconvertToString(x), (Spindle_num.SpindleStartE1));
            Spindle_num.SpindleEndE1 = cellfun(@(x)SpindleTimeconvertToString(x), (Spindle_num.SpindleEndE1));
            app.storeSpindleInfo = Spindle_num;
            
            app.spindleInfo_UITable.Data = Spindle_num;
            app.spindleInfo_UITable.ColumnName = {'フレーム番号','スピンドル数','スピンドルのスタート時間','スピンドルの終了時間'};

            app.SpindleTot_EditField.Value = spindleToT;
            app.SpindleTot_EditField.Editable ='off';
            app.updatePlotSpindle(1);

            app.ToTFrameNum = length(RUSB_estimation)/params.sample_per_30s;
            app.FrameSlider.Limits =[1 app.ToTFrameNum ];
            step = 50;

            app.FrameSlider.MajorTicks = app.FrameSlider.Limits(1):step:app.FrameSlider.Limits(2);
            app.FrameSlider.MinorTicks = app.FrameSlider.Limits(1):step/10:app.FrameSlider.Limits(2);

            close(d);
        end


        function updatePlotSpindle(app,frameNum)
            app.NumOfSpindleInFramer_EditField.Editable ='on';
            [~,file,~] =  fileparts(app.fileFeature);
            dirname = fullfile(app.folderFig,file);

            app.FrameNum_EditField.Value = frameNum;
            file2plot = dirname+"_"+string(frameNum)+".png";
            img = imread(file2plot);
            imshow(img,'Parent',app.UIAxes_spindleDetect);

            app.FrameNum_EditField.Value = frameNum;
            spindleTable =app.storeSpindleInfo;
            FrameNumberTable = cell2mat(spindleTable.FrameNumber);
            app.NumOfSpindleInFramer_EditField.Value = cell2mat(spindleTable.spindleNum(FrameNumberTable ==frameNum));

            app.NumOfSpindleInFramer_EditField.Editable ='off';

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             disp('Start APP')
            addpath ..\Lib\;

            if (~exist(app.folderPreProc,'file'))
                mkdir (app.folderPreProc);
            end

            % parameter
            app.params.T  = app.EditField_TimeRange.Value;
            app.params.Fs = app.EditField_FS.Value;%読み込むEDFファイルのサンプリングレートに変更
            app.params.win_len = app.EditField_win_len.Value;
            app.params.slide_len = app.EditField_slide_len.Value;
            app.params.rate = app.EditField_rate.Value;
            app.params.sample_per_30s = app.EditField_sample_per_30s.Value;

            app.params.Fs_Y = app.params.Fs;
            app.params.TimeRange_Y = app.params.T ;

            %今回はスピンドル10000件、ノンスピンドル70000件に不均衡を調整
            app.params.nSpindle = app.EditField_nSpindle.Value;  % Num
            app.params.underSamplingFact = app.EditField_underSamplingFact.Value; % u
            app.EditField_nNonSpindle.Value = app.EditField_nSpindle.Value * app.EditField_underSamplingFact.Value;

            app.EditField_modelSaveFolder.Value = app.defaultModel;
            app.fileModel = app.defaultModel;


        end

        % Close request function: UIFigure2
        function UIFigure2CloseRequest(app, event)
            delete(app)
            close all;
        end

        % Button pushed function: Button_EDFFile
        function Button_EDFFilePushed(app, event)
            app.fileEDF = app.pickFile('*.edf','EDF ファイルを選択してください');

            app.EditField_EDFFilePath.Value = app.fileEDF ;

        end

        % Button pushed function: Button_sleepFile
        function Button_sleepFilePushed(app, event)
            app.fileSleep = app.pickFile('*.csv','睡眠データを選択してください');

            app.EditField_sleepFilePath.Value = app.fileSleep ;

        end

        % Button pushed function: Button_featureFolder
        function Button_featureFolderPushed(app, event)
            app.folderFeature = uigetdir(pwd, 'フィーチャーの保存先を設定してください');
            app.EditField_featureFolderPath.Value = app.folderFeature;

        end

        % Callback function
        function CheckBox_preProcValueChanged(app, event)
            value = app.CheckBox_preProc.Value;

            if value
                app.Button_computePreProc.Enable ='on';
            else
                app.Button_computePreProc.Enable ='off';
            end
        end

        % Button pushed function: Button_parameterSetting
        function Button_parameterSettingPushed(app, event)
            app.TabGroup.SelectedTab     = app.Tab_setting;

        end

        % Button pushed function: Button_computePreProc
        function Button_computePreProcPushed(app, event)
            d = uiprogressdlg(app.UIFigure2,'Title','Featureの計算中',...
                'Indeterminate','on');
            drawnow

            [~,fileEDF,~] = fileparts(app.fileEDF);
            [~,filesleep,~] = fileparts(app.fileSleep);

            if (strcmp(fileEDF, filesleep))

                PSGData = readEDF(app.fileEDF, app.params);
                sleepData = readSleep(app.fileSleep);

                params = app.params;

                EEG_epoch = splitData( PSGData.EEG(:,1) , sleepData,app.params);
                [SST,sst_coeff,f] = computeSSTcoef(EEG_epoch, params);

                [Features,SubData] = FeatureExtraction(SST,params,EEG_epoch);

                [~,file,~] = fileparts(app.fileEDF);
                file = fullfile(app.folderPreProc,file+".mat");

                app.EEG_epoch = EEG_epoch  ;
                save(file,'PSGData','sleepData','sst_coeff','f','params','EEG_epoch')

                % Set dropdown for EEG plot
                nEEG = size(app.EEG_epoch);
                frame = string(1:nEEG);
                app.FramenumberDropDown.Items = frame';

                app.plot_EEGqc(app.UIAxes_EEG_QC,app.EEG_epoch{1,:})

                % Display sleep data
                app.UITable_Sleepdata.Data = sleepData;
                app.UITable_Sleepdata.ColumnName = sleepData.Properties.VariableNames;

                [~,file,~] = fileparts(app.fileEDF);
                file = fullfile(app.folderFeature,file+".mat");
                save(file,'Features','SubData')

                app.EditField_FeatureFilePath.Value =  file;
                app.fileFeature = file;

                close(d);

            else
                uialert(app.UIFigure2,sprintf('睡眠とEDFファイルが一致していません。\n再設定してください。'),'Featureの計算ができません');
            end

        end

        % Value changed function: EditField_TimeRange
        function EditField_TimeRangeValueChanged(app, event)
            app.params.T  = app.EditField_TimeRange.Value;
            app.params.TimeRange_Y   = app.EditField_TimeRange.Value;

        end

        % Value changed function: FramenumberDropDown
        function FramenumberDropDownValueChanged(app, event)
            value = str2double(app.FramenumberDropDown.Value);

            app.plot_EEGqc(app.UIAxes_EEG_QC,app.EEG_epoch{value,:})
        end

        % Button pushed function: Button_EDFFolder_3
        function Button_EDFFolder_3Pushed(app, event)
            app.fileFeature = app.pickFile('*.mat','Featureファイルを選択してください');

            app.EditField_FeatureFilePath.Value = app.fileFeature ;
        end

        % Button pushed function: Button_detectSpindle
        function Button_detectSpindlePushed(app, event)
            if (isempty(app.EditField_FeatureFilePath.Value))
                uialert(app.UIFigure2,'Featureファイルを設定してください','Featureファイルが設定設定されていません');
            else
                app.progressDetectSpindle();
            end

        end

        % Button pushed function: Button_modelFolder
        function Button_modelFolderPushed(app, event)
            app.fileModel = app.pickFile('*.mat','Modelファイルを選択してください');

            app.EditField_modelSaveFolder.Value = app.fileModel ;
        end

        % Button pushed function: Button_FigFolder
        function Button_FigFolderPushed(app, event)
            app.folderFig = uigetdir(pwd, '図の保存先を設定してください');
            app.EditField_FigFolder.Value = app.folderFig;

        end

        % Callback function
        function Button_preprocFolderPushed(app, event)

        end

        % Button pushed function: Button_saveSettingToFile_2
        function Button_saveSettingToFile_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_preproc;

        end

        % Button pushed function: Button_saveSettingToFile_3
        function Button_saveSettingToFile_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.spindle_Tab;

        end

        % Button pushed function: Button_readSettingParFromSetting
        function Button_readSettingParFromSettingPushed(app, event)
            [file,path] = uiputfile('*.xml','パラメター設定の保存');
            if isequal(file,0)
                disp('User selected Cancel')
                filename = "";
            else
                disp(['User selected ', fullfile(path, file)])
                filename = fullfile(path, file);
            end

            % update params
            app.params.T  = app.EditField_TimeRange.Value;
            app.params.Fs = app.EditField_FS.Value;%読み込むEDFファイルのサンプリングレートに変更
            app.params.win_len = app.EditField_win_len.Value;
            app.params.slide_len = app.EditField_slide_len.Value;
            app.params.rate = app.EditField_rate.Value;
            app.params.sample_per_30s = app.EditField_sample_per_30s.Value;

            app.params.Fs_Y = app.params.Fs;
            app.params.TimeRange_Y = app.params.T ;

            %今回はスピンドル10000件、ノンスピンドル70000件に不均衡を調整
            app.params.nSpindle = app.EditField_nSpindle.Value;  % Num
            app.params.underSamplingFact = app.EditField_underSamplingFact.Value; % u

            params = app.params;

            % save to file
            writestruct(params, filename);

        end

        % Button pushed function: Button_saveSettingToFile
        function Button_saveSettingToFilePushed(app, event)
            filename = app.pickFile('*.xml','パラメターファイルを選択してください');

            params = readstruct(filename);
            app.params = params;

            app.EditField_TimeRange.Value = params.T ;
            app.EditField_FS.Value = params.Fs ;%読み込むEDFファイルのサンプリングレートに変更
            app.EditField_win_len.Value = params.win_len;
            app.EditField_slide_len.Value = params.slide_len ;
            app.EditField_rate.Value = params.rate;
            app.EditField_sample_per_30s.Value = params.sample_per_30s;

            app.params.T = params.TimeRange_Y  ;

            %今回はスピンドル10000件、ノンスピンドル70000件に不均衡を調整
            app.EditField_nSpindle.Value = params.nSpindle ;  % Num
            app.EditField_underSamplingFact.Value = params.underSamplingFact; % u

        end

        % Button pushed function: Button_computePreProc_2
        function Button_computePreProc_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.spindle_Tab;

        end

        % Image clicked function: ImageL
        function ImageLClicked(app, event)
            totFile = app.ToTFrameNum;

            frameNum = app.FrameNum_EditField.Value;

            if (frameNum ==1)
                nextFrame = totFile;
            else
                nextFrame = frameNum -1;
            end

            app.updatePlotSpindle(nextFrame);
            app.FrameSlider.Value = nextFrame;


        end

        % Image clicked function: Image2R
        function Image2RClicked(app, event)
            totFile = app.ToTFrameNum;

            frameNum = app.FrameNum_EditField.Value;

            if (frameNum ==totFile)
                nextFrame = 1;
            else
                nextFrame = frameNum +1;
            end

            app.updatePlotSpindle(nextFrame);
            app.FrameSlider.Value = nextFrame;

        end

        % Value changed function: FrameNum_EditField
        function FrameNum_EditFieldValueChanged(app, event)
            value = app.FrameNum_EditField.Value;
            app.FrameSlider.Value = value;
            app.updatePlotSpindle(value);

        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = round(app.FrameSlider.Value);
            app.FrameNum_EditField.Value = value;
            app.updatePlotSpindle(value);

        end

        % Button pushed function: saveToExcel_Button
        function saveToExcel_ButtonPushed(app, event)
            Spindle_num = app.storeSpindleInfo;
            Spindle_num.Properties.VariableNames = {'フレーム番号','スピンドル数','スピンドルのスタート時間','スピンドルの終了時間'};

            [file,path] = uiputfile('*.xlsx','保存のファイルを設定');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file)])
                filename = fullfile(path, file);
            end

            writetable(Spindle_num,filename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure2 = uifigure('Visible', 'off');
            app.UIFigure2.Name = 'MATLAB App';
            app.UIFigure2.CloseRequestFcn = createCallbackFcn(app, @UIFigure2CloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure2);
            app.Tab_preproc = uitab(app.TabGroup);
            app.Tab_preproc.Title = 'Preprocessiong';
            app.Button_computePreProc = uibutton(app.Tab_preproc, 'push');
            app.Button_computePreProc.ButtonPushedFcn = createCallbackFcn(app, @Button_computePreProcPushed, true);
            app.Button_computePreProc.Text = {'Calculate '; 'Feature Matrix'};
            app.Button_parameterSetting = uibutton(app.Tab_preproc, 'push');
            app.Button_parameterSetting.ButtonPushedFcn = createCallbackFcn(app, @Button_parameterSettingPushed, true);
            app.Button_parameterSetting.Text = 'Parameter Setting';
            app.TabGroup3 = uitabgroup(app.Tab_preproc);
            app.EEGTab = uitab(app.TabGroup3);
            app.EEGTab.Title = 'EEG';
            app.UIAxes_EEG_QC = uiaxes(app.EEGTab);
            zlabel(app.UIAxes_EEG_QC, 'Z')
            app.FramenumberDropDownLabel = uilabel(app.EEGTab);
            app.FramenumberDropDownLabel.Text = 'Frame number';
            app.FramenumberDropDown = uidropdown(app.EEGTab);
            app.FramenumberDropDown.ValueChangedFcn = createCallbackFcn(app, @FramenumberDropDownValueChanged, true);
            app.Tab_Data = uitab(app.TabGroup3);
            app.Tab_Data.Title = 'Sleep data';
            app.UITable_Sleepdata = uitable(app.Tab_Data);
            app.UITable_Sleepdata.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Sleepdata.RowName = {};
            app.Button_computePreProc_2 = uibutton(app.Tab_preproc, 'push');
            app.Button_computePreProc_2.ButtonPushedFcn = createCallbackFcn(app, @Button_computePreProc_2Pushed, true);
            app.Button_computePreProc_2.Text = {'Sleep Spindle'; 'Detection'};
            app.Button_EDFFile = uibutton(app.Tab_preproc, 'push');
            app.Button_EDFFile.ButtonPushedFcn = createCallbackFcn(app, @Button_EDFFilePushed, true);
            app.Button_EDFFile.Text = 'Set';
            app.EditField_EDFFilePath = uieditfield(app.Tab_preproc, 'text');
            app.EDFLabel = uilabel(app.Tab_preproc);
            app.EDFLabel.Text = 'EDF data file：';
            app.Button_sleepFile = uibutton(app.Tab_preproc, 'push');
            app.Button_sleepFile.ButtonPushedFcn = createCallbackFcn(app, @Button_sleepFilePushed, true);
            app.Button_sleepFile.Text = 'Set';
            app.EditField_sleepFilePath = uieditfield(app.Tab_preproc, 'text');
            app.Label_10 = uilabel(app.Tab_preproc);
            app.Label_10.Text = 'Sleep stage file：';
            app.Button_featureFolder = uibutton(app.Tab_preproc, 'push');
            app.Button_featureFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_featureFolderPushed, true);
            app.Button_featureFolder.Text = 'Set';
            app.EditField_featureFolderPath = uieditfield(app.Tab_preproc, 'text');
            app.Label_11 = uilabel(app.Tab_preproc);
            app.Label_11.Text = 'Feature matrix save folder：';
            app.spindle_Tab = uitab(app.TabGroup);
            app.spindle_Tab.Title = 'Sleep Spindle Detection';
            app.EditField_FeatureFilePath = uieditfield(app.spindle_Tab, 'text');
            app.Label_7 = uilabel(app.spindle_Tab);
            app.Label_7.Text = 'Displayed file name：';
            app.Button_EDFFolder_3 = uibutton(app.spindle_Tab, 'push');
            app.Button_EDFFolder_3.ButtonPushedFcn = createCallbackFcn(app, @Button_EDFFolder_3Pushed, true);
            app.Button_EDFFolder_3.Text = 'load';
            app.Button_detectSpindle = uibutton(app.spindle_Tab, 'push');
            app.Button_detectSpindle.ButtonPushedFcn = createCallbackFcn(app, @Button_detectSpindlePushed, true);
            app.Button_detectSpindle.Text = 'Sleep Spindle Detection';
            app.Label_9 = uilabel(app.spindle_Tab);
            app.Label_9.Text = '';
            app.TabGroup4 = uitabgroup(app.spindle_Tab);
            app.DetectionResultTab = uitab(app.TabGroup4);
            app.DetectionResultTab.Title = 'Detection Result';
            app.UIAxes_spindleDetect = uiaxes(app.DetectionResultTab);
            zlabel(app.UIAxes_spindleDetect, 'Z')
            app.ImageL = uiimage(app.DetectionResultTab);
            app.ImageL.ImageClickedFcn = createCallbackFcn(app, @ImageLClicked, true);
            app.ImageL.ImageSource = 'left.png';
            app.Image2R = uiimage(app.DetectionResultTab);
            app.Image2R.ImageClickedFcn = createCallbackFcn(app, @Image2RClicked, true);
            app.Image2R.ImageSource = 'Right.png';
            app.FrameSlider = uislider(app.DetectionResultTab);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.EpochnumberLabel = uilabel(app.DetectionResultTab);
            app.EpochnumberLabel.Text = 'Epoch number';
            app.FrameNum_EditField = uieditfield(app.DetectionResultTab, 'numeric');
            app.FrameNum_EditField.ValueChangedFcn = createCallbackFcn(app, @FrameNum_EditFieldValueChanged, true);
            app.FrameNum_EditField.Value = 1;
            app.EditField_3Label_2 = uilabel(app.DetectionResultTab);
            app.EditField_3Label_2.Text = 'Number of Sleep Spindles';
            app.NumOfSpindleInFramer_EditField = uieditfield(app.DetectionResultTab, 'numeric');
            app.TotalNumberofSleepSpindlesLabel = uilabel(app.DetectionResultTab);
            app.TotalNumberofSleepSpindlesLabel.Text = 'Total Number of Sleep Spindles';
            app.SpindleTot_EditField = uieditfield(app.DetectionResultTab, 'numeric');
            app.SpindleTot_EditField.ValueDisplayFormat = '%.0f';
            app.ResultTable = uitab(app.TabGroup4);
            app.ResultTable.Title = 'Result Table';
            app.spindleInfo_UITable = uitable(app.ResultTable);
            app.spindleInfo_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.spindleInfo_UITable.RowName = {};
            app.saveToExcel_Button = uibutton(app.ResultTable, 'push');
            app.saveToExcel_Button.ButtonPushedFcn = createCallbackFcn(app, @saveToExcel_ButtonPushed, true);
            app.saveToExcel_Button.Text = 'エクセルへ保存';
            app.Tab_setting = uitab(app.TabGroup);
            app.Tab_setting.Title = 'Setting';
            app.saveFolderPanel = uipanel(app.Tab_setting);
            app.saveFolderPanel.Title = 'Setting the Save Folder';
            app.Button_modelFolder = uibutton(app.saveFolderPanel, 'push');
            app.Button_modelFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_modelFolderPushed, true);
            app.Button_modelFolder.Text = 'Set';
            app.Button_FigFolder = uibutton(app.saveFolderPanel, 'push');
            app.Button_FigFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_FigFolderPushed, true);
            app.Button_FigFolder.Text = 'Set';
            app.EditField_modelSaveFolder = uieditfield(app.saveFolderPanel, 'text');
            app.Label_8 = uilabel(app.saveFolderPanel);
            app.Label_8.Text = 'Model File：';
            app.EditField_FigFolder = uieditfield(app.saveFolderPanel, 'text');
            app.FiguresLabel = uilabel(app.saveFolderPanel);
            app.FiguresLabel.Text = 'Figures：';
            app.Panel_paramterSetting = uipanel(app.Tab_setting);
            app.Panel_paramterSetting.Title = 'Parameters setting';
            app.EDFdataPanel = uipanel(app.Panel_paramterSetting);
            app.EDFdataPanel.Title = 'EDF data';
            app.TimerangesLabel = uilabel(app.EDFdataPanel);
            app.TimerangesLabel.Text = 'Time range (s):';
            app.EditField_TimeRange = uieditfield(app.EDFdataPanel, 'numeric');
            app.EditField_TimeRange.Limits = [0 Inf];
            app.EditField_TimeRange.ValueChangedFcn = createCallbackFcn(app, @EditField_TimeRangeValueChanged, true);
            app.EditField_TimeRange.Value = 30;
            app.SamplingrateFsLabel = uilabel(app.EDFdataPanel);
            app.SamplingrateFsLabel.Text = 'Sampling rate (Hz): ';
            app.EditField_FS = uieditfield(app.EDFdataPanel, 'numeric');
            app.EditField_FS.Limits = [0 Inf];
            app.EditField_FS.Value = 200;
            app.Panel_spindle = uipanel(app.Panel_paramterSetting);
            app.Panel_spindle.Title = 'Leaning Settings';
            app.NumberofSleepSpindlesLabel = uilabel(app.Panel_spindle);
            app.NumberofSleepSpindlesLabel.Text = 'Number of Sleep Spindles:';
            app.EditField_nSpindle = uieditfield(app.Panel_spindle, 'numeric');
            app.EditField_nSpindle.Limits = [0 Inf];
            app.EditField_nSpindle.ValueDisplayFormat = '%.0f';
            app.EditField_nSpindle.Value = 10000;
            app.NumberofnonSleepSpindlesLabel = uilabel(app.Panel_spindle);
            app.NumberofnonSleepSpindlesLabel.Text = 'Number of non Sleep Spindles: ';
            app.EditField_nNonSpindle = uieditfield(app.Panel_spindle, 'numeric');
            app.EditField_nNonSpindle.Limits = [0 Inf];
            app.EditField_nNonSpindle.ValueDisplayFormat = '%.0f';
            app.EditField_nNonSpindle.Value = 70000;
            app.Label = uilabel(app.Panel_spindle);
            app.Label.Text = 'Percentage of non-Sleep Spindle: ';
            app.EditField_underSamplingFact = uieditfield(app.Panel_spindle, 'numeric');
            app.EditField_underSamplingFact.Limits = [0 Inf];
            app.EditField_underSamplingFact.ValueDisplayFormat = '%.0f';
            app.EditField_underSamplingFact.Value = 7;
            app.Panel_procParam = uipanel(app.Panel_paramterSetting);
            app.Panel_procParam.Title = 'Processing Parameters';
            app.win_lenLabel = uilabel(app.Panel_procParam);
            app.win_lenLabel.Text = 'win_len : ';
            app.EditField_win_len = uieditfield(app.Panel_procParam, 'numeric');
            app.EditField_win_len.Limits = [0 Inf];
            app.EditField_win_len.Value = 0.5;
            app.slide_lengthLabel = uilabel(app.Panel_procParam);
            app.slide_lengthLabel.Text = 'slide_length：';
            app.EditField_slide_len = uieditfield(app.Panel_procParam, 'numeric');
            app.EditField_slide_len.Limits = [0 Inf];
            app.EditField_slide_len.Value = 0.25;
            app.rateEditFieldLabel = uilabel(app.Panel_procParam);
            app.rateEditFieldLabel.Text = 'rate：';
            app.EditField_rate = uieditfield(app.Panel_procParam, 'numeric');
            app.EditField_rate.Limits = [0 Inf];
            app.EditField_rate.Value = 0.75;
            app.sample_per_30sEditFieldLabel = uilabel(app.Panel_procParam);
            app.sample_per_30sEditFieldLabel.Text = 'sample_per_30s：';
            app.EditField_sample_per_30s = uieditfield(app.Panel_procParam, 'numeric');
            app.EditField_sample_per_30s.Limits = [0 Inf];
            app.EditField_sample_per_30s.Value = 110;
            app.Button_readSettingParFromSetting = uibutton(app.Tab_setting, 'push');
            app.Button_readSettingParFromSetting.ButtonPushedFcn = createCallbackFcn(app, @Button_readSettingParFromSettingPushed, true);
            app.Button_readSettingParFromSetting.Text = 'Save to File';
            app.Button_saveSettingToFile = uibutton(app.Tab_setting, 'push');
            app.Button_saveSettingToFile.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSettingToFilePushed, true);
            app.Button_saveSettingToFile.Text = 'Set from file';
            app.Button_saveSettingToFile_2 = uibutton(app.Tab_setting, 'push');
            app.Button_saveSettingToFile_2.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSettingToFile_2Pushed, true);
            app.Button_saveSettingToFile_2.Text = 'Jump to Pre-processing';
            app.Button_saveSettingToFile_3 = uibutton(app.Tab_setting, 'push');
            app.Button_saveSettingToFile_3.ButtonPushedFcn = createCallbackFcn(app, @Button_saveSettingToFile_3Pushed, true);
            app.Button_saveSettingToFile_3.Text = 'Jump to Spindle Detection';
            % Show the figure after all components are created
            app.UIFigure2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpindleAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure2)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure2)
        end
    end
end